classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        LogsTab                    matlab.ui.container.Tab
        AtualizarLogsButton        matlab.ui.control.Button
        LogsTextArea               matlab.ui.control.TextArea
        LogsTextAreaLabel          matlab.ui.control.Label
        MtricasTab                 matlab.ui.container.Tab
        RTTMinEditField            matlab.ui.control.NumericEditField
        RTTMnimoLabel              matlab.ui.control.Label
        RTTMaxEditField            matlab.ui.control.NumericEditField
        RTTMximoLabel              matlab.ui.control.Label
        ThroughputEditField        matlab.ui.control.NumericEditField
        ThroughputEditFieldLabel   matlab.ui.control.Label
        PacotesEditField           matlab.ui.control.NumericEditField
        PacotesEditFieldLabel      matlab.ui.control.Label
        RTTMedioEditField          matlab.ui.control.NumericEditField
        RTTMdioLabel               matlab.ui.control.Label
        GrficosTab                 matlab.ui.container.Tab
        UIAxesTP                   matlab.ui.control.UIAxes
        UIAxesRTT                  matlab.ui.control.UIAxes
        ResultadosdeDesempenhoTab  matlab.ui.container.Tab
        UITableResultados          matlab.ui.control.Table
        UIAxesResultadoRTT         matlab.ui.control.UIAxes
        UIAxesResultadoTP          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AtualizarLogsButton
        function AtualizarLogsButtonPushed(app, event)

    % =====================================
    % LOGS DO SERVIDOR
    % =====================================

    arquivo = fullfile('logs','servidor.log');

    if exist(arquivo,'file')

        texto = fileread(arquivo);

        app.LogsTextArea.Value = splitlines(texto);

    else

        app.LogsTextArea.Value = {'Arquivo não encontrado'};

    end

    % =====================================
    % MÉTRICAS
    % =====================================

    if exist('metricas.mat','file')

        dados = load('metricas.mat');

        % RTT médio
        app.RTTMedioEditField.Value = ...
            mean(dados.rttHistorico);

        % RTT mínimo
        app.RTTMinEditField.Value = ...
            min(dados.rttHistorico);

        % RTT máximo
        app.RTTMaxEditField.Value = ...
            max(dados.rttHistorico);

        % Throughput médio
        app.ThroughputEditField.Value = ...
            mean(dados.tpHistorico);

        % Total de pacotes
        app.PacotesEditField.Value = ...
            length(dados.rttHistorico);

        % =====================
        % HISTÓRICO RTT
        % =====================

        cla(app.UIAxesRTT)

        plot(app.UIAxesRTT,...
            dados.rttHistorico,...
            '-o',...
            'LineWidth',2)

        grid(app.UIAxesRTT,'on')

        title(app.UIAxesRTT,...
            'Histórico RTT')

        xlabel(app.UIAxesRTT,...
            'Pacote')

        ylabel(app.UIAxesRTT,...
            'Tempo (s)')

        % =====================
        % HISTÓRICO THROUGHPUT
        % =====================

        cla(app.UIAxesTP)

        plot(app.UIAxesTP,...
            dados.tpHistorico,...
            '-o',...
            'LineWidth',2)

        grid(app.UIAxesTP,'on')

        title(app.UIAxesTP,...
            'Histórico Throughput')

        xlabel(app.UIAxesTP,...
            'Pacote')

        ylabel(app.UIAxesTP,...
            'Bytes/s')

    end

    % =====================================
    % RESULTADOS DOS TESTES
    % =====================================

    if exist('resultadoDesempenho.mat','file')

        load('resultadoDesempenho.mat','resultado')

        % Tabela

        app.UITableResultados.Data = resultado;

        app.UITableResultados.ColumnName = { ...
            'Tamanho (Bytes)', ...
            'RTT Médio (s)', ...
            'Throughput Médio (Bytes/s)'};

        app.UITableResultados.ColumnFormat = ...
            {'numeric','numeric','numeric'};

        app.UITableResultados.FontSize = 12;

        % Valores do eixo X

        tamanhos = resultado(:,1);

        % =====================
        % RTT x TAMANHO
        % =====================

        cla(app.UIAxesResultadoRTT)

        plot(app.UIAxesResultadoRTT,...
            tamanhos,...
            resultado(:,2),...
            '-o',...
            'LineWidth',2)

        grid(app.UIAxesResultadoRTT,'on')

        title(app.UIAxesResultadoRTT,...
            'RTT Médio x Tamanho da Mensagem')

        xlabel(app.UIAxesResultadoRTT,...
            'Tamanho (Bytes)')

        ylabel(app.UIAxesResultadoRTT,...
            'RTT Médio (s)')

        % =====================
        % TP x TAMANHO
        % =====================

        cla(app.UIAxesResultadoTP)

        plot(app.UIAxesResultadoTP,...
            tamanhos,...
            resultado(:,3),...
            '-o',...
            'LineWidth',2)

        grid(app.UIAxesResultadoTP,'on')

        title(app.UIAxesResultadoTP,...
            'Throughput Médio x Tamanho da Mensagem')

        xlabel(app.UIAxesResultadoTP,...
            'Tamanho (Bytes)')

        ylabel(app.UIAxesResultadoTP,...
            'Bytes/s')

    end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LogsTab = uitab(app.TabGroup);
            app.LogsTab.Title = 'Logs';
            app.LogsTextAreaLabel = uilabel(app.LogsTab);
            app.LogsTextAreaLabel.Text = 'Logs';
            app.LogsTextArea = uitextarea(app.LogsTab);
            app.AtualizarLogsButton = uibutton(app.LogsTab, 'push');
            app.AtualizarLogsButton.ButtonPushedFcn = createCallbackFcn(app, @AtualizarLogsButtonPushed, true);
            app.AtualizarLogsButton.Text = 'Atualizar Logs';
            app.MtricasTab = uitab(app.TabGroup);
            app.MtricasTab.Title = 'Métricas';
            app.RTTMdioLabel = uilabel(app.MtricasTab);
            app.RTTMdioLabel.Text = 'RTT Médio';
            app.RTTMedioEditField = uieditfield(app.MtricasTab, 'numeric');
            app.RTTMedioEditField.Editable = 'off';
            app.PacotesEditFieldLabel = uilabel(app.MtricasTab);
            app.PacotesEditFieldLabel.Text = 'Pacotes';
            app.PacotesEditField = uieditfield(app.MtricasTab, 'numeric');
            app.PacotesEditField.Editable = 'off';
            app.ThroughputEditFieldLabel = uilabel(app.MtricasTab);
            app.ThroughputEditFieldLabel.Text = 'Throughput';
            app.ThroughputEditField = uieditfield(app.MtricasTab, 'numeric');
            app.ThroughputEditField.Editable = 'off';
            app.RTTMximoLabel = uilabel(app.MtricasTab);
            app.RTTMximoLabel.Text = 'RTT Máximo';
            app.RTTMaxEditField = uieditfield(app.MtricasTab, 'numeric');
            app.RTTMaxEditField.Editable = 'off';
            app.RTTMnimoLabel = uilabel(app.MtricasTab);
            app.RTTMnimoLabel.Text = 'RTT Mínimo';
            app.RTTMinEditField = uieditfield(app.MtricasTab, 'numeric');
            app.RTTMinEditField.Editable = 'off';
            app.GrficosTab = uitab(app.TabGroup);
            app.GrficosTab.Title = 'Gráficos';
            app.UIAxesRTT = uiaxes(app.GrficosTab);
            title(app.UIAxesRTT, 'RTT')
            xlabel(app.UIAxesRTT, 'X')
            ylabel(app.UIAxesRTT, 'Y')
            zlabel(app.UIAxesRTT, 'Z')
            app.UIAxesTP = uiaxes(app.GrficosTab);
            title(app.UIAxesTP, 'Throughput')
            xlabel(app.UIAxesTP, 'X')
            ylabel(app.UIAxesTP, 'Y')
            zlabel(app.UIAxesTP, 'Z')
            app.ResultadosdeDesempenhoTab = uitab(app.TabGroup);
            app.ResultadosdeDesempenhoTab.Title = 'Resultados de Desempenho';
            app.UIAxesResultadoTP = uiaxes(app.ResultadosdeDesempenhoTab);
            title(app.UIAxesResultadoTP, 'Title')
            xlabel(app.UIAxesResultadoTP, 'X')
            ylabel(app.UIAxesResultadoTP, 'Y')
            zlabel(app.UIAxesResultadoTP, 'Z')
            app.UIAxesResultadoRTT = uiaxes(app.ResultadosdeDesempenhoTab);
            title(app.UIAxesResultadoRTT, 'Title')
            xlabel(app.UIAxesResultadoRTT, 'X')
            ylabel(app.UIAxesResultadoRTT, 'Y')
            zlabel(app.UIAxesResultadoRTT, 'Z')
            app.UITableResultados = uitable(app.ResultadosdeDesempenhoTab);
            app.UITableResultados.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITableResultados.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end